classdef MFCC_GMM_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        GridLayout                     matlab.ui.container.GridLayout
        SimilaritytoKnownSpekersPanel  matlab.ui.container.Panel
        UIAxes                         matlab.ui.control.UIAxes
        GridLayout2                    matlab.ui.container.GridLayout
        StartClassificationButton      matlab.ui.control.Button
        GridLayout3                    matlab.ui.container.GridLayout
        GridLayout4                    matlab.ui.container.GridLayout
        CurrentSpeakersButton          matlab.ui.control.Button
        AddNewSpeakerButton            matlab.ui.control.Button
        GridLayout5                    matlab.ui.container.GridLayout
        CurrentMaximumspeakercountEditFieldLabel  matlab.ui.control.Label
        CurrentMaximumspeakercountEditField  matlab.ui.control.EditField
    end

    
    properties (Access = public)
        fh
    end
    
    properties (Access = private, Constant)
        bufferSize  = 10
        maxSpeakers = 10        % Maximum allowed speaker count
    end
    
    properties (Access = private)
        E_th
        fs
        gm
        k
        nCoef
        nSp
        n_30ms
        names
        deviceReader
        piHat
    end
    
    methods (Access = private)
        
        % Refreshes the plot. Changes in bin count is accounted for.
        function RefreshPlot(app)
            % Restarting:
            cla(app.UIAxes);
            set(app.UIAxes, 'xtick', 1:min(app.nSp, app.maxSpeakers));
            
            % UI setup
            bar(app.UIAxes,app.piHat);
            
            set(app.UIAxes, 'xticklabel', app.names);
            xlabel(app.UIAxes, 'Speaker')
            ylabel(app.UIAxes, 'Similarity')
            axtoolbar(app.UIAxes,{'restoreview'});
            app.UIAxes.Interactions = [ ...
                dataTipInteraction, ...
                zoomInteraction('Dimensions','y'), ...
                panInteraction('Dimensions','y')];
            drawnow;
        end
        
        function addSpeakerToGMM(app, aIn, name)
            try
                % Get current model parameters
                mus    = app.gm.mu;
                Sigmas = app.gm.Sigma;
                p      = app.gm.ComponentProportion;
                
                if size(aIn,2) ~= 1 % Not mono audio
                    aIn = mean(aIn,2); % Then get intrachannel average
                end
                feature = getMFCC(aIn,app.fs,app.nCoef,app.E_th);
                newGm = fitgmdist(feature,app.k, ...
                    'RegularizationValue',1e-12, ...
                    'Options',statset('MaxIter',1500));
                
                mus(end+1:end+app.k,:)      = newGm.mu;
                Sigmas(:,:,end+1:end+app.k) = newGm.Sigma;
                
                p = p * app.nSp;
                p(end+1:end+app.k)   = newGm.ComponentProportion;
                
                % Update speaker count & the model if successful
                app.nSp = app.nSp + 1;
                app.names{end+1} = name;
                p = p / app.nSp;
                app.gm = gmdistribution(mus,Sigmas,p);
            catch ME
                if strcmp(ME.identifier, 'getMFCC:notVoiced')
                    uialert(app.UIFigure, ME.message, 'Error adding speaker!')
                else
                    rethrow(ME);
                end
            end
        end
    end
    
    methods (Access = private, Static)

        function [coef, nVd] = getMFCC(aIn,fs,nCoef,E_th)
        %GETMFCC Get MFCC coefficients from voiced segments.
        %
        %    This function checks for voiced speech in 30 ms
        % segments with 10ms overlap at each side with Hamming
        % window applied. For voiced segments, the MFCC algorithm
        % is called.
        %
        %   Inputs:
        %
        %   aIn     Audio input as a double array
        %   fs      Sampling frequency in Hz
        %   nCoef   Number of MFCC coefficients requested
        %   E_th    The threshold average energy for
        %           voiced/unvoiced decision
        % 
        %   Outputs:
        %
        %   coef    MFCC coefficients of voiced segments
        %   nVd     Number of voiced segments
        %
        
            %% Constants
            n_10ms = 0.01*fs;
            w = hamming(3*n_10ms);
        
            %% Data & basic features
            if sum(size(aIn) ~= 1) ~= 1
                error('getMFCC:multichannel', ...
                    'Error!\nThe given audio input is multichannel. ' + ...
                    'This function accepts single channel inputs only.' + ...
                    'MFCC feature extraction failed.');
            end
            aIn = aIn / max(aIn);
            L = floor(length(aIn)/n_10ms);
        
            %% Preprocessing, VAD
            y = reshape(aIn(1:n_10ms*L),[n_10ms,L]);
            y = [y(:,1:end-2);y(:,2:end-1);y(:,3:end)];
            y = y .* (w*ones(1,L-2));
            E = sum(y.^2) ./ (3*n_10ms);
            isVoiced = E > E_th;
            nVd = sum(isVoiced);
        
            % Throw an error if there is not at least 50 ms of voiced speech
            if nVd < 3
                error('getMFCC:notVoiced', ...
                    "The given audio doesn't have enough voiced segments. " + ...
                    'MFCC feature extraction failed.');
            end
        
            y = y(:,isVoiced);
        
            %% MFCC feature extraction
            coef = mfcc(y,fs,'NumCoeffs',nCoef-1);
            coef = permute(coef,[2,3,1]);
            % An attempt to minimize channel effects:
            coef = coef - ones(nCoef,1)*mean(coef);
        end

        function [features] = getMFCC_stat(aIn,fs,nCoef,E_th)
        %GETMFCC_STAT MFCC feature mean & covariance matrix extraction from audio.
        %   Some details here
        
            %% Get entire MFCC data
            [coef,nVoiced] = getMFCC(aIn,fs,nCoef,E_th);
            covM_triu = (1:nCoef) >= (1:nCoef)';
        
            %% Extract statistical features
            avgV = mean(coef,2);
            coef = coef-avgV;
            covM = coef*coef'/(nVoiced - 1);
        
            features = [avgV;covM(covM_triu)];
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Algorithm setup
            featureStr = load('MFCC_GMM_features.mat');
            
            app.fs         = featureStr.fs;
            app.E_th       = featureStr.E_th;
            app.nCoef      = featureStr.nCoef;
            app.k          = featureStr.k;
            app.names      = featureStr.names;
            app.gm         = featureStr.gm;
            
            app.nSp        = length(app.names);
            app.n_30ms     = round(app.fs * 0.03);
            
            app.deviceReader = audioDeviceReader(app.fs, app.n_30ms);
            setup(app.deviceReader);
            
            app.piHat = zeros(app.nSp,1);
            
            % UI setup
            RefreshPlot(app);
            grid(app.UIAxes);
            app.UIAxes.YLim = [0, 1];
            zoom(app.UIAxes, 0.9);
            
            app.fh = @() uiresume(app.UIFigure);
        end

        % Button pushed function: StartClassificationButton
        function StartClassificationButtonPushed(app, event)
            % Check if the initial state was on or off, respectively.
            if strcmp(app.CurrentSpeakersButton.Enable,'on')
                % Disable other functions & change self label
                app.CurrentSpeakersButton.Enable = 'off';
                app.AddNewSpeakerButton.Enable = 'off';
                app.StartClassificationButton.Text = {'Stop';'Classification'};
                
                % Algorithm setup
                y_buff = zeros(4*app.n_30ms,1);
                y_buff = circshift(y_buff,-app.n_30ms);
                y_buff(end-app.n_30ms+1:end) = app.deviceReader();
                
                piHats = zeros(app.nSp, app.bufferSize);
                
                % Begin classification
                while strcmp(app.CurrentSpeakersButton.Enable,'off')
                    y_buff = circshift(y_buff,-app.n_30ms);
                    y_buff(end-app.n_30ms+1:end) = app.deviceReader();
                    try
                        coef = getMFCC(y_buff,app.fs,app.nCoef,3*app.E_th);
                        [~,~,probs] = cluster(app.gm, coef');
                        probs = sum(reshape(probs,[size(probs,1),app.k,app.nSp]),2);
                        probs = mean(probs,1);
                        probs = probs(:);
                        
                        piHats = circshift(piHats,-1,2);
                        piHats(:,end) = probs;
                        app.piHat = mean(piHats,2);
                        
                        RefreshPlot(app);
                    catch ME
                        if strcmp(ME.identifier, 'getMFCC:notVoiced')
                            disp(ME.identifier)
                        else
                            rethrow(ME);
                        end
                    end
                end
            else
                % Reenable other functions & change self label
                app.CurrentSpeakersButton.Enable = 'on';
                app.AddNewSpeakerButton.Enable = 'on';
                app.StartClassificationButton.Text = {'Start';'Classification'};
            end
        end

        % Button pushed function: AddNewSpeakerButton
        function AddNewSpeakerButtonPushed(app, event)
            fig = app.UIFigure;
            if app.nSp >= app.maxSpeakers
                uialert(fig, ...
                    "The maximum number of speakers achieved.\n" + ...
                    "No new speakers can be added. Exiting.", ...
                    "Error adding speaker!");
                return
            end
            name = inputdlg("Enter the speaker identifier (e.g. name)", ...
                "Set speaker identifier", [1,70]);
            uialert(fig, ...
                "Sample recording will start after you press OK.\n" + ...
                "It will automatically cease after 40 seconds.", ...
                "Starting recording", ...
                "Icon", 'info', 'CloseFcn','app.fh');
            uiwait(fig);
            
            aIn = [];
            tic
            while toc < 6
                aIn(end+1:end+app.n_30ms,1) = app.deviceReader();
            end
            uialert(fig, ...
                "Sample recording has ended.", ...
                "Recording ended", "Icon", 'info', 'CloseFcn','app.fh');
            uiwait(app.UIFigure);
            
            addSpeakerToGMM(app, aIn, name);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.SimilaritytoKnownSpekersPanel = uipanel(app.GridLayout);
            app.SimilaritytoKnownSpekersPanel.Title = 'Similarity to Known Spekers';
            app.UIAxes = uiaxes(app.SimilaritytoKnownSpekersPanel);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.StartClassificationButton = uibutton(app.GridLayout2, 'push');
            app.StartClassificationButton.ButtonPushedFcn = createCallbackFcn(app, @StartClassificationButtonPushed, true);
            app.StartClassificationButton.Text = {'Start'; 'Classification'};
            app.GridLayout3 = uigridlayout(app.GridLayout2);
            app.GridLayout4 = uigridlayout(app.GridLayout3);
            app.CurrentSpeakersButton = uibutton(app.GridLayout4, 'push');
            app.CurrentSpeakersButton.Text = 'Current Speakers';
            app.AddNewSpeakerButton = uibutton(app.GridLayout4, 'push');
            app.AddNewSpeakerButton.ButtonPushedFcn = createCallbackFcn(app, @AddNewSpeakerButtonPushed, true);
            app.AddNewSpeakerButton.Text = 'Add New Speaker';
            app.GridLayout5 = uigridlayout(app.GridLayout3);
            app.CurrentMaximumspeakercountEditFieldLabel = uilabel(app.GridLayout5);
            app.CurrentMaximumspeakercountEditFieldLabel.Text = 'Current / Maximum speaker count:';
            app.CurrentMaximumspeakercountEditField = uieditfield(app.GridLayout5, 'text');
            app.CurrentMaximumspeakercountEditField.Editable = 'off';
            app.CurrentMaximumspeakercountEditField.Value = '0 / 5';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MFCC_GMM_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end