classdef basic_ui_test2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        GridLayout                     matlab.ui.container.GridLayout
        GridLayout2                    matlab.ui.container.GridLayout
        StartClassificationButton      matlab.ui.control.Button
        GridLayout3                    matlab.ui.container.GridLayout
        GridLayout6                    matlab.ui.container.GridLayout
        CurrentSpeakersButton          matlab.ui.control.Button
        AddnewspeakerButton            matlab.ui.control.Button
        GridLayout7                    matlab.ui.container.GridLayout
        CurrentMaximumspeakercountEditFieldLabel  matlab.ui.control.Label
        CurrentMaximumspeakercountEditField  matlab.ui.control.EditField
        SimilaritytoKnownSpekersPanel  matlab.ui.container.Panel
        UIAxes                         matlab.ui.control.UIAxes
    end

    
    properties (Constant)
        p = 10                  % LP degree
        Fs = 44100              % Sampling frequency
        n_10ms = 441            % 10 ms worth of samples ~ Change manually
        E_threshold = 0.0100    % Voiced/Unvoiced decision threshold
        N_max = 100             % Online calculation window width
        BufferSize = 11         % 10 ms bin count in the FIFO audio buffer
        MaxSpeakers = 10        % Maximum allowed speaker count
    end
    
    properties (Access = public)
        Features                % Feature struct array of different speakers
        DeviceReader            % Voice input reader
        PiHat                   % Speaker probability/similarity array
        y_buff                  % Buffer for audio data
    end
    
    properties (Access = private)
        Feature                 % Dummy feature struct for speaker addition
    end
    
    methods (Access = private)
        
        % Refreshes the plot. Changes in bin count is accounted for.
        function RefreshPlot(app)
            % Restarting:
            cla(app.UIAxes);
            set(app.UIAxes, 'xtick', 1:app.MaxSpeakers);
            
            % UI setup
            bar(app.UIAxes,app.PiHat);
            
            set(app.UIAxes, 'xticklabel', {app.Features.name});
            xlabel(app.UIAxes, 'Speaker')
            ylabel(app.UIAxes, 'Similarity')
            axtoolbar(app.UIAxes,{'restoreview'});
            app.UIAxes.Interactions = [ ...
                dataTipInteraction, ...
                zoomInteraction('Dimensions','y'), ...
                panInteraction('Dimensions','y')];
            app.UIAxes.YAxisLocation = 'origin';
            drawnow;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Algorithm setup
            tmp = load('LSP_features.mat');
            app.Features = tmp.features;
            
            app.DeviceReader = audioDeviceReader(app.Fs, app.n_10ms);
            setup(app.DeviceReader);
            
            app.Feature.name = "";
            app.Feature.covm = zeros([app.p,app.p]);
            app.Feature.mean = zeros([app.p,1]);
%            app.Features = struct('name',{},'covm',{},'mean',{});
%            Preloaded right now.
            
            app.PiHat = [zeros(length(app.Features), 1)];
            app.y_buff = zeros(app.BufferSize*app.n_10ms,1);
            
            % UI setup
            RefreshPlot(app);
            grid(app.UIAxes);
            zoom(app.UIAxes, 0.9);
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            selection = uiconfirm(app.UIFigure,'Exit the app?','Confirm Close');
            switch selection
                case 'OK'
                    delete(app)
                otherwise
                    return
            end
        end

        % Button pushed function: StartClassificationButton
        function StartClassificationButtonPushed(app, event)
            % Check if the initial state was on or off, respectively.
            if strcmp(app.CurrentSpeakersButton.Enable,'on')
                % Disable other functions & change self label
                app.CurrentSpeakersButton.Enable = 'off';
                app.AddnewspeakerButton.Enable = 'off';
                app.StartClassificationButton.Text = {'Stop';'Classification'};
                
                m_v   = zeros([app.p,1]);
                cov_m = zeros([app.p,app.p]);
                N     = 1;
                
                for i = 1:app.BufferSize-1
                    app.y_buff = [app.y_buff(app.n_10ms+1:app.BufferSize*app.n_10ms,1); app.DeviceReader()];
                end
                
                % Begin classification
                while strcmp(app.CurrentSpeakersButton.Enable,'off')
                    pause(0.001);
                    app.y_buff = [app.y_buff(app.n_10ms+1:app.BufferSize*app.n_10ms,1); app.DeviceReader()];
                    
                    E = sum(app.y_buff.^2)/(app.BufferSize*app.n_10ms);
                
                    if E >= app.E_threshold
                
                        a = lpc(app.y_buff,app.p);
                        lsp = poly2lsf(a) - m_v;
                
                        cov_m = cov_m.*(1 - 1/N) + lsp*lsp'./(N+1);
                        m_v = m_v + lsp./(N+1);
                
                        for i = 1:length(app.Features)
                            app.PiHat(i) = 1./(1 + norm(app.Features(i).covm - cov_m, 'fro'));
                        end
                
                        if N <= app.N_max
                            N = N + 1;
                        end
                
                        RefreshPlot(app);
                    end
                end
            else
                % Reenable other functions & change self label
                app.CurrentSpeakersButton.Enable = 'on';
                app.AddnewspeakerButton.Enable = 'on';
                app.StartClassificationButton.Text = {'Start';'Classification'};
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.StartClassificationButton = uibutton(app.GridLayout2, 'push');
            app.StartClassificationButton.ButtonPushedFcn = createCallbackFcn(app, @StartClassificationButtonPushed, true);
            app.StartClassificationButton.Text = {'Start'; 'Classification'};
            app.GridLayout3 = uigridlayout(app.GridLayout2);
            app.GridLayout6 = uigridlayout(app.GridLayout3);
            app.CurrentSpeakersButton = uibutton(app.GridLayout6, 'push');
            app.CurrentSpeakersButton.Text = 'Current Speakers';
            app.AddnewspeakerButton = uibutton(app.GridLayout6, 'push');
            app.AddnewspeakerButton.Text = 'Add new speaker';
            app.GridLayout7 = uigridlayout(app.GridLayout3);
            app.CurrentMaximumspeakercountEditFieldLabel = uilabel(app.GridLayout7);
            app.CurrentMaximumspeakercountEditFieldLabel.Text = 'Current / Maximum speaker count:';
            app.CurrentMaximumspeakercountEditField = uieditfield(app.GridLayout7, 'text');
            app.CurrentMaximumspeakercountEditField.Editable = 'off';
            app.CurrentMaximumspeakercountEditField.Value = '0 / 5';
            app.SimilaritytoKnownSpekersPanel = uipanel(app.GridLayout);
            app.SimilaritytoKnownSpekersPanel.Title = 'Similarity to Known Spekers';
            app.UIAxes = uiaxes(app.SimilaritytoKnownSpekersPanel);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = basic_ui_test2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end